classdef Inteligent_DFT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Intelligent_Signal_Reader       matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Concept                         matlab.ui.container.Tab
        SignalLength2Label              matlab.ui.control.Label
        Signal_Length_In                matlab.ui.control.NumericEditField
        CarrierFreqHzEditFieldLabel     matlab.ui.control.Label
        Carrier_Freq_In                 matlab.ui.control.NumericEditField
        CarrierAmplitudeVLabel          matlab.ui.control.Label
        Carrier_Amp_In                  matlab.ui.control.NumericEditField
        AmplitudeNoiseLabel             matlab.ui.control.Label
        Amplitude_Noise_Knob            matlab.ui.control.Knob
        PhaseNoiseLabel                 matlab.ui.control.Label
        Phase_Noise_Knob                matlab.ui.control.Knob
        FMModulationLabel               matlab.ui.control.Label
        Add_Signal                      matlab.ui.control.ToggleSwitch
        FrequencyOffsetLabel            matlab.ui.control.Label
        Ctrl_Sig_Freq                   matlab.ui.control.ToggleSwitch
        Normal_Axes                     matlab.ui.control.UIAxes
        PSNRLabel                       matlab.ui.control.Label
        Normal_PSNR                     matlab.ui.control.NumericEditField
        SignalOffsetHzLabel             matlab.ui.control.Label
        Signal_Offset                   matlab.ui.control.NumericEditField
        SamplingFreqHzEditFieldLabel    matlab.ui.control.Label
        Sampling_Freq_In                matlab.ui.control.NumericEditField
        Training_Ctrls                  matlab.ui.container.Panel
        LearningRateLabel               matlab.ui.control.Label
        LR_Update                       matlab.ui.control.DropDown
        LearnRLabel                     matlab.ui.control.Label
        LR_Numeric                      matlab.ui.control.NumericEditField
        RegularisationLabel             matlab.ui.control.Label
        RR_Update                       matlab.ui.control.DropDown
        RegularRLabel                   matlab.ui.control.Label
        RR_Numeric                      matlab.ui.control.NumericEditField
        Run_Training_Button             matlab.ui.control.Button
        Reset_Button                    matlab.ui.control.Button
        MaxNumEpochsLabel               matlab.ui.control.Label
        Num_Epochs_In                   matlab.ui.control.NumericEditField
        WeightUpdateLabel               matlab.ui.control.Label
        Weight_Update                   matlab.ui.control.DropDown
        RegFunctionLabel                matlab.ui.control.Label
        Regularisation                  matlab.ui.control.DropDown
        LossFunctionLabel               matlab.ui.control.Label
        Loss_Func_In                    matlab.ui.control.DropDown
        ComplexityLabel                 matlab.ui.control.Label
        Complexity_In                   matlab.ui.control.DropDown
        Export_Training_Data_Button     matlab.ui.control.Button
        Store_Training_Data             matlab.ui.control.CheckBox
        Ideal_Axes                      matlab.ui.control.UIAxes
        Predicted_Axes                  matlab.ui.control.UIAxes
        PSNRLabel_2                     matlab.ui.control.Label
        Predicted_PSNR                  matlab.ui.control.NumericEditField
        MSELabel                        matlab.ui.control.Label
        Normal_MSE                      matlab.ui.control.NumericEditField
        MSELabel_2                      matlab.ui.control.Label
        Predicted_MSE                   matlab.ui.control.NumericEditField
        MaxErrLabel                     matlab.ui.control.Label
        Normal_MaxErr                   matlab.ui.control.NumericEditField
        MaxErrLabel_2                   matlab.ui.control.Label
        Predicted_MaxErr                matlab.ui.control.NumericEditField
        L2ratLabel                      matlab.ui.control.Label
        Normal_L2rat                    matlab.ui.control.NumericEditField
        L2ratLabel_2                    matlab.ui.control.Label
        Predicted_L2rat                 matlab.ui.control.NumericEditField
        Report                          matlab.ui.container.Tab
        Generate_Report_Button          matlab.ui.control.Button
        PSNR_Plot                       matlab.ui.control.UIAxes
        MSE_Plot                        matlab.ui.control.UIAxes
        MaxErr_Plot                     matlab.ui.control.UIAxes
        L2rat_Plot                      matlab.ui.control.UIAxes
        Play_Progress_Button            matlab.ui.control.Button
        Twiddle_Mag_Plot                matlab.ui.control.UIAxes
        Twiddle_Phase_Plot              matlab.ui.control.UIAxes
        Demonstration                   matlab.ui.container.Tab
        System_Model                    matlab.ui.container.Panel
        Tx                              matlab.ui.control.Image
        Medium                          matlab.ui.control.Image
        Rx                              matlab.ui.control.Image
        StandardSelectionLabel          matlab.ui.control.Label
        Standard_Select                 matlab.ui.control.DropDown
        MediumSelectionLabel            matlab.ui.control.Label
        Medium_Select                   matlab.ui.control.DropDown
        CarrierFreqHzEditFieldLabel_2   matlab.ui.control.Label
        Demo_Carrier_Freq               matlab.ui.control.NumericEditField
        SignalOffsetHzLabel_2           matlab.ui.control.Label
        Demo_Signal_Offset              matlab.ui.control.NumericEditField
        SamplingFreqHzEditFieldLabel_2  matlab.ui.control.Label
        Demo_Sampling_Freq              matlab.ui.control.NumericEditField
        TxLabel                         matlab.ui.control.Label
        MediumLabel                     matlab.ui.control.Label
        RxLabel                         matlab.ui.control.Label
        NumSamples2Label                matlab.ui.control.Label
        Demo_Num_Samples                matlab.ui.control.NumericEditField
        SystemSetupLabel                matlab.ui.control.Label
        SystemImperfectionsLabel        matlab.ui.control.Label
        Quadrature_Error                matlab.ui.control.CheckBox
        LO_Leakage                      matlab.ui.control.CheckBox
        PA_Non_Linearity                matlab.ui.control.CheckBox
        MediumClarityLabel              matlab.ui.control.Label
        Medium_Clarity                  matlab.ui.control.DropDown
        Run_Initial_Calibration         matlab.ui.control.CheckBox
        Run_Model                       matlab.ui.control.Button
        TrainCfgDropDownLabel           matlab.ui.control.Label
        TrainCfgDropDown                matlab.ui.control.DropDown
        Tx_Data                         matlab.ui.container.Panel
        AvailableSymbolsEditFieldLabel  matlab.ui.control.Label
        Available_Symbols               matlab.ui.control.EditField
        TransmittedDataEditFieldLabel   matlab.ui.control.Label
        Transmitted_Data                matlab.ui.control.EditField
        Tx_Freq                         matlab.ui.control.UIAxes
        Rx_Data                         matlab.ui.container.Panel
        MLReceivedDataLabel             matlab.ui.control.Label
        ML_Received_Data                matlab.ui.control.EditField
        NormalReceivedDataLabel         matlab.ui.control.Label
        Norm_Received_Data              matlab.ui.control.EditField
        Rx_Freq                         matlab.ui.control.UIAxes
        Error                           matlab.ui.container.Panel
        MLSymbolErrorRateLabel          matlab.ui.control.Label
        ML_Symbol_Error                 matlab.ui.control.NumericEditField
        NormSymbolErrorRateLabel        matlab.ui.control.Label
        Norm_Symbol_Error               matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        %---------------------------------------------------------------------------------------
        % These properties are going to be used for both the training AND
        % the Demonstration tabs.
        %---------------------------------------------------------------------------------------
        
        %% Signal Properties
        %---------------------------------------------------------------------------------------
        signal_length % Num samples in the signal
        carrier_freq % Carrier frequency for the system
        carrier_amp % Amplitude of the carrier signal
        signal_offset % Offset for the additional signal
        sampling_freq % Sampling frequency
        
        fm_mod % Boolean value for FM Modulation
        signal_offset_is_rand % Boolean value controlling whther the signal offest is random
        
        amplitude_noise % Control Value for the amplitude noise
        phase_noise % Control Value for the phase noise
        %---------------------------------------------------------------------------------------
        
        %% Training Properties
        %---------------------------------------------------------------------------------------
        Twiddle_Array_Orig % Original Twiddle Array for DFT
        Twiddle_Array_Train % The trained Twiddle Array for ML DFT
        
        Num_Epochs % Max Number of Epochs
        Current_Epoch_Num % Current Epoch Number
        
        LR % Learning Rate Numeric
        RR % Regularisation Rate Numeric
        
        Loss_Func % Function to compute Delta Value
        LR_Update_Rule % Function to update LR dynamically
        Weight_Update_Rule % Function to update the Twiddle_Array_Train values
        
        Complexity_Score % Function to compute Systems complexity
        RR_Update_Rule % Function to update Regularisation Rate dynamically
        Regularisation_Method % Function to handle minimising complexity in the system
        
        Normal_Metrics_To_Save % Placeholder Array for the Normal DFT's error metric outputs
        Prediction_Metrics_To_Save % Placeholder Array for the Intelligent DFT's error metric outputs
        Twiddle_Array_Training_Progress % Placeholder for the trained twiddle array at each epoch
        %---------------------------------------------------------------------------------------
        
    end
    
    methods (Access = private)
        
        function [fft_freq, fft_actual, fft_mag] = fft_and_post_process(app, input_sig)
            %fft_and_post_process performs all necessary computations to calculate a signal's fft
            %in a manner that is easy to interpret visually.
            Y = fft(input_sig);
            fft_actual = Y;
            
            P2 = abs(Y/app.signal_length);
            P1 = P2(1:app.signal_length/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            
            fft_freq = app.sampling_freq*(0:(app.signal_length/2))/app.signal_length;
            fft_mag = P1;
        end
        
        function [] = generate_twiddle_array(app)
            %generate_twiddle_array generates and saves twiddle arrays used in dft's
            if isempty(app.Twiddle_Array_Orig)
                app.Run_Training_Button.Text = "Making Twiddle...";
                app.Run_Training_Button.BackgroundColor = [0.96, 0.45, 0.96];
                drawnow()
                
                num_samples = app.signal_length;
                Array = zeros(num_samples, num_samples);
                for a = 1 : (num_samples)
                    for b = 1 : (num_samples)
                        Array(a, b) = twiddle_factor(app, ((a-1) * (b-1)));
                    end
                end
                
                app.Twiddle_Array_Orig = Array;
                app.Twiddle_Array_Train = app.Twiddle_Array_Orig;
            end
        end
        
        function [twiddle] = twiddle_factor(app, p)
            %Generates a single twiddle factor given input arguments N and p
            twiddle = exp(-1i * (2 * p * pi)/app.signal_length);
        end
        
        function [g] = generate_signal(app, amplitude_noise_profile, phase_noise_profile)
            %generate signal given the setup of the app and the
            %(optional) offset frequency and noise profiles.
            T = 1/app.sampling_freq;
            t = (0 : app.signal_length-1) * T;
            
            if app.fm_mod
                if app.signal_offset_is_rand
                    offset = -(app.signal_offset/2) + (app.signal_offset) * rand(1);
                    g = amplitude_noise_profile + (app.carrier_amp * sin((2 * pi * app.carrier_freq * t) + phase_noise_profile) + (app.carrier_amp - 3) * sin((2 * pi * (app.carrier_freq + offset) * t) + phase_noise_profile));
                else
                    g = amplitude_noise_profile + (app.carrier_amp * sin((2 * pi * app.carrier_freq * t) + phase_noise_profile) + (app.carrier_amp - 3) * sin((2 * pi * (app.carrier_freq + app.signal_offset) * t) + phase_noise_profile));
                end
            else
                g = amplitude_noise_profile + (app.carrier_amp * sin((2 * pi * app.carrier_freq * t) + phase_noise_profile));
            end
            
        end
        
        function [] = update_training_input_vals(app)
            %Updates all training values based on UI inputs.
            app.signal_length = 2 ^ app.Signal_Length_In.Value;
            app.carrier_freq = app.Carrier_Freq_In.Value;
            app.carrier_amp = app.Carrier_Amp_In.Value;
            
            app.signal_offset = app.Signal_Offset.Value;
            app.sampling_freq = app.Sampling_Freq_In.Value;
            
            if strcmp(app.Add_Signal.Value, 'On')
                app.fm_mod = true;
            else
                app.fm_mod = false;
            end
            
            if strcmp(app.Ctrl_Sig_Freq.Value, 'Random')
                app.signal_offset_is_rand = true;
            else
                app.signal_offset_is_rand = false;
            end
            
            app.amplitude_noise = app.Amplitude_Noise_Knob.Value;
            app.phase_noise = app.Phase_Noise_Knob.Value;
            
            app.RR_Update_Rule = app.RR_Update.Value;
            app.RR = app.RR_Numeric.Value;
            app.Regularisation_Method = app.Regularisation.Value;
            app.Complexity_Score = app.Complexity_In.Value;
            
            app.LR_Update_Rule = app.LR_Update.Value;
            app.LR = app.LR_Numeric.Value;
            app.Weight_Update_Rule = app.Weight_Update.Value;
            app.Loss_Func = app.Loss_Func_In.Value;
            
            app.Num_Epochs = app.Num_Epochs_In.Value - 1;
        end
        
        
        function [] = update_demo_input_vals(app)
            %Updates all demonstration values based on UI inputs.
            app.signal_length = 2 ^ app.Demo_Num_Samples.Value;
            app.carrier_freq = app.Demo_Carrier_Freq.Value;
            app.carrier_amp = 20;
            
            app.signal_offset = app.Demo_Signal_Offset.Value;
            app.sampling_freq = app.Demo_Sampling_Freq.Value;
            
            app.fm_mod = false;
            
            app.signal_offset_is_rand = false;
            
            app.amplitude_noise = 20;
            app.phase_noise = 3;
            
            app.RR_Update_Rule = [];
            app.RR = [];
            app.Regularisation_Method = [];
            app.Complexity_Score = [];
            
            app.LR_Update_Rule = [];
            app.LR = [];
            app.Weight_Update_Rule = [];
            app.Loss_Func = [];
            
            app.Num_Epochs = 0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Defaults all input values to safe and known values.
            update_training_input_vals(app);
            
            % Training Init Vals
            app.Twiddle_Array_Orig = [];
            app.Twiddle_Array_Train = [];
            app.Current_Epoch_Num = 0;
            
            % Report Init Vals
            app.Normal_Metrics_To_Save = [];
            app.Prediction_Metrics_To_Save = [];
            app.Twiddle_Array_Training_Progress = [];
            
            % Demo Init Vals
            directory = pwd;
            image_path = strcat(directory, '\Demonstration Images\');
            app.Tx.ImageSource = strcat(image_path, 'DMR_tx.PNG');
            app.Rx.ImageSource = strcat(image_path, 'DMR_rx.PNG');
            app.Medium.ImageSource = strcat(image_path, 'Cable.PNG');
        end

        % Close request function: Intelligent_Signal_Reader
        function Intelligent_Signal_ReaderCloseRequest(app, event)
            app.Reset_Button.Text = "Reset";
            app.Reset_Button.BackgroundColor = [0.96, 0.96, 0.96];
            
            Reset_ButtonPushed(app)
            app.Amplitude_Noise_Knob.Value = 0.0;
            app.Phase_Noise_Knob.Value = 0.0;
            delete(app)
            clear
        end

        % Button pushed function: Run_Training_Button
        function Run_Training_ButtonPushed(app, event)
            try
                %First Ensure that the function is fit to run
                if strcmp(app.Run_Training_Button.Text, "Continue Training")
                    app.Current_Epoch_Num = 0;
                end
                
                % Begin training
                while app.Current_Epoch_Num <= app.Num_Epochs
                    % Change colours and texts of UI buttons to convey what the
                    % app is doing to the user
                    app.Run_Training_Button.Text = "Preparing to Train...";
                    app.Run_Training_Button.BackgroundColor = [0.96, 0.45, 0.45];
                    
                    app.Reset_Button.Text = "Stop";
                    app.Reset_Button.BackgroundColor = [0.96, 0.45, 0.45];
                    drawnow()
                    
                    % Make sure all input values are up to date
                    update_training_input_vals(app)
                    
                    % Generate twiddle arrays (if needed)
                    generate_twiddle_array(app)
                    
                    % Change button text for the user
                    app.Run_Training_Button.Text = "Training...";
                    app.Run_Training_Button.BackgroundColor = [0.45, 0.96, 0.45];
                    drawnow()
                    
                    % Generate clean profiles for the ideal signal
                    clean_amp_profile = ones(1, app.signal_length);
                    clean_phase_profile = zeros(1, app.signal_length);
                    
                    % Generate noise profiles for the real input signal
                    amp_noise_profile = -(app.amplitude_noise/2) + (app.amplitude_noise) * rand(1, app.signal_length);
                    phase_noise_profile = -(app.phase_noise/2) + (app.phase_noise) * rand(1, app.signal_length);
                    
                    % Generate ideal and real signals.
                    [ideal_signal] = generate_signal(app, clean_amp_profile, clean_phase_profile);
                    [noisy_signal] = generate_signal(app, amp_noise_profile, phase_noise_profile);
                    
                    [clean_freq, ideal_fft, ideal_fft_mag] = fft_and_post_process(app, ideal_signal);
                    [noisy_freq, ~, noisy_fft_mag] = fft_and_post_process(app, noisy_signal);
                    
                    % Compute the DFT using the custom Twiddle Array, then also compute its
                    % one-sided magnitude.
                    predicted_freq = mtimes(noisy_signal, app.Twiddle_Array_Train);
                    
                    P2 = abs(predicted_freq / app.signal_length);
                    P1 = P2(1 : app.signal_length/2+1);
                    P1(2 : end-1) = 2 * P1(2 : end-1);
                    
                    prediction = P1;
                    
                    % Plot all info and adjust plot axes.
                    plot(app.Ideal_Axes, clean_freq, ideal_fft_mag)
                    plot(app.Normal_Axes, noisy_freq, noisy_fft_mag)
                    plot(app.Predicted_Axes, noisy_freq, prediction)
                    
                    app.Normal_Axes.YLim = app.Ideal_Axes.YLim;
                    app.Predicted_Axes.YLim = app.Ideal_Axes.YLim;
                    
                    % Extract error metrics for sake of comparison.
                    [normal_psnr, normal_mse, normal_maxerr, normal_L2rat] = measerr(ideal_fft_mag, noisy_fft_mag);
                    [predicted_psnr, predicted_mse, predicted_maxerr, predicted_L2rat] = measerr(ideal_fft_mag, prediction);
                    
                    % Print Errors to screen and change display colour to
                    % indicate performance.
                    
                    % PSNR: Higher is better
                    app.Normal_PSNR.Value = normal_psnr;
                    app.Predicted_PSNR.Value = predicted_psnr;
                    
                    if predicted_psnr >= normal_psnr
                        app.Normal_PSNR.BackgroundColor = [0.96, 0.45, 0.45];
                        app.Predicted_PSNR.BackgroundColor = [0.45, 0.96, 0.45];
                    else
                        app.Normal_PSNR.BackgroundColor = [0.45, 0.96, 0.45];
                        app.Predicted_PSNR.BackgroundColor = [0.96, 0.45, 0.45];
                    end
                    
                    % Mean Square Error: Lower is better
                    app.Normal_MSE.Value = normal_mse;
                    app.Predicted_MSE.Value = predicted_mse;
                    
                    if predicted_mse <= normal_mse
                        app.Normal_MSE.BackgroundColor = [0.96, 0.45, 0.45];
                        app.Predicted_MSE.BackgroundColor = [0.45, 0.96, 0.45];
                    else
                        app.Normal_MSE.BackgroundColor = [0.45, 0.96, 0.45];
                        app.Predicted_MSE.BackgroundColor = [0.96, 0.45, 0.45];
                    end
                    
                    % Maximum Error: Lower is better
                    app.Normal_MaxErr.Value = normal_maxerr;
                    app.Predicted_MaxErr.Value = predicted_maxerr;
                    
                    if predicted_maxerr <= normal_maxerr
                        app.Normal_MaxErr.BackgroundColor = [0.96, 0.45, 0.45];
                        app.Predicted_MaxErr.BackgroundColor = [0.45, 0.96, 0.45];
                    else
                        app.Normal_MaxErr.BackgroundColor = [0.45, 0.96, 0.45];
                        app.Predicted_MaxErr.BackgroundColor = [0.96, 0.45, 0.45];
                    end
                    
                    % L2 Ratio: Closer to 1 is better
                    app.Normal_L2rat.Value = normal_L2rat;
                    app.Predicted_L2rat.Value = predicted_L2rat;
                    
                    if abs(1 - predicted_L2rat) <= abs(1 - normal_L2rat)
                        app.Normal_L2rat.BackgroundColor = [0.96, 0.45, 0.45];
                        app.Predicted_L2rat.BackgroundColor = [0.45, 0.96, 0.45];
                    else
                        app.Normal_L2rat.BackgroundColor = [0.45, 0.96, 0.45];
                        app.Predicted_L2rat.BackgroundColor = [0.96, 0.45, 0.45];
                    end
                    
                    drawnow()
                    
                    % Append this epoch's error metrics to the corresponding
                    % arrays for export later.
                    if app.Store_Training_Data.Value
                        app.Normal_Metrics_To_Save = [app.Normal_Metrics_To_Save; [normal_psnr, normal_mse, normal_maxerr, normal_L2rat]];
                        app.Prediction_Metrics_To_Save = [app.Prediction_Metrics_To_Save; [predicted_psnr, predicted_mse, predicted_maxerr, predicted_L2rat]];
                    
                        % Save the twiddle array used during this epoch.
                        app.Twiddle_Array_Training_Progress = [app.Twiddle_Array_Training_Progress; app.Twiddle_Array_Train];
                    end
                    
                    % Perform the training according to the user's specifications
                    
                    % First thing to establish is the Loss Function. This provides
                    % the measure for how inaccurate the system is.
                    switch(app.Loss_Func)
                        
                        case "Difference"
                            loss = (predicted_freq - ideal_fft);
                            
                        case "Difference Squared"
                            loss = (predicted_freq - ideal_fft) .^ 2;
                            
                        case "Difference of Squares"
                            loss = ((predicted_freq.^2) - (ideal_fft.^2));
                            
                        case "EVM"
                            loss = sqrt((abs((predicted_freq - ideal_fft)).^2) ./ (abs(ideal_fft).^2));
                            
                        otherwise
                            loss = (predicted_freq - ideal_fft);
                            
                    end
                    
                    % Now compute the gradient of the loss w.r.t. the system
                    % weights.
                    gradient = mtimes(noisy_signal.', loss);
                    
                    % Next we update the Learning Rate, if a dynamic learning rate
                    % is desired. This can help prevent the system from diverging
                    % if used correctly.
                    switch(app.LR_Update_Rule)
                        
                        case "Constant"
                            Learning_Rate = app.LR;
                            
                        case "Root of Sum of Squares"
                            Learning_Rate = app.LR * (sqrt(sum(loss.^2)));
                            
                        case "6th root of Sum of Mags"
                            Learning_Rate = app.LR * sum(abs(loss)).^(1/6);
                            
                        case "|mean(loss)| / |max(loss)|"
                            Learning_Rate = app.LR * (abs(mean(loss)) / abs(max(loss)));
                            
                        case "mean(|loss|) / max(|loss|)"
                            Learning_Rate = app.LR * (mean(abs(loss)) / max(abs(loss)));
                            
                        case "EVM"
                            Learning_Rate = app.LR * sqrt(sum(angle(loss) .^ 2) / sum(abs(loss) .^ 2));
                            
                        otherwise
                            Learning_Rate = app.LR;
                            
                    end
                    
                    % Finally, based on the weight update rule, we acquire the
                    % next set of weight updates.
                    switch(app.Weight_Update_Rule)
                        
                        case "dE/dW"
                            dEdW = gradient;
                            
                        case "sqrt(dE/dW)"
                            dEdW = sqrt(gradient);
                            
                        case "(dE/dW)/max(dE/dW)"
                            dEdW = (gradient / max(gradient));
                            
                        otherwise
                            dEdW = gradient;
                            
                    end
                    
                    % If regularisation is desired, we first need to score the
                    % complexity of the system. No matter the method desired, it
                    % must always be based on the difference between the current
                    % system and the unchanged system.
                    switch(app.Complexity_Score)
                        
                        case "None"
                            Complexity = zeros(app.signal_length);
                            
                        case "W_train - W_orig"
                            Complexity = app.Twiddle_Array_Train - app.Twiddle_Array_Orig;
                            
                        case "(W_train - W_orig)^2"
                            Complexity = (app.Twiddle_Array_Train - app.Twiddle_Array_Orig) .^ 2;
                            
                        case "(W_train^2 - W_orig^2)"
                            Complexity = ((app.Twiddle_Array_Train).^2 - (app.Twiddle_Array_Orig).^2);
                            
                        case "sqrt(W_train^2 - W_orig^2)"
                            Complexity = sqrt((app.Twiddle_Array_Train).^2 - (app.Twiddle_Array_Orig).^2);
                            
                        otherwise
                            Complexity = 0;
                            
                    end
                    
                    % Same idea as the learning rate update, just applied to the
                    % regularisation rate instaed.
                    switch(app.RR_Update_Rule)
                        
                        case "Constant"
                            Regularisation_Rate = app.RR;
                            
                        case "Root of Sum of Squares"
                            Regularisation_Rate = app.RR * (sqrt(sum(Complexity.^2)));
                            
                        case "6th root of Sum of Mags"
                            Regularisation_Rate = app.RR * sum(abs(Complexity)).^(1/6);
                            
                        case "|mean(complexity)| / |max(complexity)|"
                            Regularisation_Rate = app.RR * (abs(mean(Complexity)) / abs(max(Complexity)));
                            
                        case "mean(|complexity|) / max(|complexity|)"
                            Regularisation_Rate = app.RR * (mean(abs(Complexity)) / max(abs(Complexity)));
                            
                        case "EVM"
                            Regularisation_Rate = app.RR * sqrt(sum(angle(Complexity) .^ 2) / sum(abs(Complexity) .^ 2));
                            
                        otherwise
                            Regularisation_Rate = app.RR;
                            
                    end
                    
                    % Same idea as the weight update rule, just applied to the
                    % regularisation. Regularisation, in this case, is used to
                    % combat the system diverging from a typical DFT.
                    switch(app.Regularisation_Method)
                        
                        case "Linear"
                            Penalty = Complexity;
                            
                        case "sqrt(Complexity)"
                            Penalty = sqrt(Complexity);
                            
                        case "(Complexity)/max(Complexity)"
                            Penalty = (Complexity / max(Complexity));
                            
                        otherwise
                            Penalty = Complexity;
                            
                    end
                    
                    app.Twiddle_Array_Train = app.Twiddle_Array_Train - (Learning_Rate * dEdW) + (Regularisation_Rate * Penalty);
                    
                    % Training Complete. Now inform he user that the epoch is
                    % complete and proceed to the next epoch.
                    app.Run_Training_Button.Text = "Epoch Complete";
                    app.Run_Training_Button.BackgroundColor = [0.45, 0.45, 0.96];
                    drawnow()
                    
                    % Control the running and termination of training.
                    app.Current_Epoch_Num = app.Current_Epoch_Num + 1;
                end
                
                Reset_ButtonPushed(app)
            catch ME
                waitfor(errordlg(ME.message, "Error During Training"));
            end
        end

        % Button pushed function: Reset_Button
        function Reset_ButtonPushed(app, event)
            try
                % If the user requests a reset, then set all buttons and
                % trained values back to their defualts and get all input values
                % from the front panel. If a stop is requested then simply
                % pause training as is and provide the user the ability to
                % restart or continue.
                if strcmp(app.Reset_Button.Text, "Reset")
                    update_training_input_vals(app)
                    
                    app.Run_Training_Button.Text = "Run Training";
                    app.Run_Training_Button.BackgroundColor = [0.96, 0.96, 0.96];
                    
                    app.Twiddle_Array_Train = [];
                    app.Twiddle_Array_Orig = [];
                    
                    app.Normal_Metrics_To_Save = [];
                    app.Prediction_Metrics_To_Save = [];
                    
                    app.Current_Epoch_Num = 0;
                    
                    cla(app.Ideal_Axes)
                    cla(app.Normal_Axes)
                    cla(app.Predicted_Axes)
                    
                    app.Normal_PSNR.Value = 0.0;
                    app.Predicted_PSNR.Value = 0.0;
                    app.Normal_PSNR.BackgroundColor = [0.96, 0.96, 0.96];
                    app.Predicted_PSNR.BackgroundColor = [0.96, 0.96, 0.96];
                    
                    app.Normal_MSE.Value = 0.0;
                    app.Predicted_MSE.Value = 0.0;
                    app.Normal_MSE.BackgroundColor = [0.96, 0.96, 0.96];
                    app.Predicted_MSE.BackgroundColor = [0.96, 0.96, 0.96];
                    
                    app.Normal_MaxErr.Value = 0.0;
                    app.Predicted_MaxErr.Value = 0.0;
                    app.Normal_MaxErr.BackgroundColor = [0.96, 0.96, 0.96];
                    app.Predicted_MaxErr.BackgroundColor = [0.96, 0.96, 0.96];
                    
                    app.Normal_L2rat.Value = 0.0;
                    app.Predicted_L2rat.Value = 0.0;
                    app.Normal_L2rat.BackgroundColor = [0.96, 0.96, 0.96];
                    app.Predicted_L2rat.BackgroundColor = [0.96, 0.96, 0.96];
                    
                elseif strcmp(app.Reset_Button.Text, "Stop")
                    app.Current_Epoch_Num = app.Num_Epochs + 1;
                    
                    app.Run_Training_Button.Text = "Continue Training";
                    app.Run_Training_Button.BackgroundColor = [0.45, 0.96, 0.45];
                    
                    app.Reset_Button.Text = "Reset";
                    app.Reset_Button.BackgroundColor = [0.96, 0.96, 0.96];
                end
                
                drawnow()
            catch ME
                waitfor(errordlg(ME.message, "Error During Stop/Reset"));
            end
        end

        % Button pushed function: Export_Training_Data_Button
        function Export_Training_Data_ButtonPushed(app, event)
            try
                waitfor(errordlg(['Be Aware! The training info from this app may take up a lot of space. Before continuing ' ...
                    'please be sure to have enough space reserved'], 'Memory Usage Warning'));
                save_path = uigetdir('C:\', "Training Progress Save Directory: ");
                if save_path ~= 0
                    Normal_DFT_Training_Data = app.Normal_Metrics_To_Save;
                    ML_DFT_Training_Data = app.Prediction_Metrics_To_Save;
                    Twiddle_Array_Over_Epochs = app.Twiddle_Array_Training_Progress;
                    
                    save(strcat(save_path, '\Normal DFT Training Data.mat'), 'Normal_DFT_Training_Data', '-v7.3')
                    save(strcat(save_path, '\ML DFT Training Data.mat'), 'ML_DFT_Training_Data', '-v7.3')
                    save(strcat(save_path, '\Twiddle Array Over Epochs.mat'), 'Twiddle_Array_Over_Epochs', '-v7.3')
                    
                    % TO DO: Provide Status of save operation so the user
                    % knows to wait for it to complete.
                    
                    Normal_DFT_Training_Data = [];
                    ML_DFT_Training_Data = [];
                    Twiddle_Array_Over_Epochs = [];
                    
                    app.Normal_Metrics_To_Save = [];
                    app.Prediction_Metrics_To_Save = [];
                    app.Twiddle_Array_Training_Progress = [];
                end
            catch ME
                waitfor(errordlg(ME.message, "Error During Data Export"));
            end
        end

        % Button pushed function: Generate_Report_Button
        function Generate_Report_ButtonPushed(app, event)
            try
                if strcmp(app.Generate_Report_Button.Text, 'Generate Report')
                    save_path = uigetdir('C:\', "Training Progress Save Directory: ");
                    if save_path ~= 0
                        load(strcat(save_path, '\Normal DFT Training Data.mat'), 'Normal_DFT_Training_Data')
                        load(strcat(save_path, '\ML DFT Training Data.mat'), 'ML_DFT_Training_Data')
                        
                        Norm_PSNR = Normal_DFT_Training_Data(:, 1);
                        Norm_MSE = Normal_DFT_Training_Data(:, 2);
                        Norm_MaxErr = Normal_DFT_Training_Data(:, 3);
                        Norm_L2rat = Normal_DFT_Training_Data(:, 4);
                        
                        ML_PSNR = ML_DFT_Training_Data(:, 1);
                        ML_MSE = ML_DFT_Training_Data(:, 2);
                        ML_MaxErr = ML_DFT_Training_Data(:, 3);
                        ML_L2rat = ML_DFT_Training_Data(:, 4);
                        
                        plot(app.PSNR_Plot, 1:size(Normal_DFT_Training_Data, 1), Norm_PSNR, 'r', 1:size(Normal_DFT_Training_Data, 1), ML_PSNR, 'b')
                        legend(app.PSNR_Plot, "Normal DFT", "ML DFT")
                        
                        plot(app.MSE_Plot, 1:size(Normal_DFT_Training_Data, 1), Norm_MSE, 'r', 1:size(Normal_DFT_Training_Data, 1), ML_MSE, 'b')
                        legend(app.MSE_Plot, "Normal DFT", "ML DFT")
                        
                        plot(app.MaxErr_Plot, 1:size(Normal_DFT_Training_Data, 1), Norm_MaxErr, 'r', 1:size(Normal_DFT_Training_Data, 1), ML_MaxErr, 'b')
                        legend(app.MaxErr_Plot, "Normal DFT", "ML DFT")
                        
                        plot(app.L2rat_Plot, 1:size(Normal_DFT_Training_Data, 1), Norm_L2rat, 'r', 1:size(Normal_DFT_Training_Data, 1), ML_L2rat, 'b')
                        legend(app.L2rat_Plot, "Normal DFT", "ML DFT")
                        
                        app.Generate_Report_Button.Text = "Clear Report";
                        app.Generate_Report_Button.BackgroundColor = [0.45, 0.96, 0.45];
                    end
                    
                else
                    app.Generate_Report_Button.Text = "Generate Report";
                    app.Generate_Report_Button.BackgroundColor = [0.96, 0.96, 0.96];
                    
                    cla(app.PSNR_Plot)
                    cla(app.MSE_Plot)
                    cla(app.MaxErr_Plot)
                    cla(app.L2rat_Plot)
                    cla(app.Twiddle_Mag_Plot)
                    cla(app.Twiddle_Phase_Plot)
                    
                    clear Normal_DFT_Training_Data; clear ML_DFT_Training_Data;
                end
                
            catch ME
                waitfor(errordlg(ME.message, "Error During Data Import"));
            end
        end

        % Button pushed function: Play_Progress_Button
        function Play_Progress_ButtonPushed(app, event)
            try
                if strcmp(app.Play_Progress_Button.Text, 'Play Progress over Epoch')
                    app.Play_Progress_Button.Text = 'Playing Twiddle Array Training...';
                    app.Play_Progress_Button.BackgroundColor = [0.45, 0.96, 0.45];
                    
                    save_path = uigetdir('C:\', "Training Progress Save Directory: ");
                    if save_path ~= 0
                        load(strcat(save_path, '\Twiddle Array Over Epochs.mat'), 'Twiddle_Array_Over_Epochs')
                        for i = 1:app.Num_Epochs
                            idx = zeros(app.signal_length, app.signal_length);
                            for j = 1:app.signal_length
                                idx(j, :) = 1:app.signal_length;
                            end
                            epoch = Twiddle_Array_Over_Epochs(1+((i-1)*app.signal_length):i*app.signal_length, :);
                            contour(app.Twiddle_Mag_Plot, abs(epoch), 16)
                            contour(app.Twiddle_Phase_Plot, angle(epoch), 16)
                            drawnow()
                            pause(1)
                        end
                        waitfor(errordlg("The animation is now complete, these plots will be cleared. To see the animation again, please press the " + ...
                            "'Play Progress over Epoch button again'", "Animation Complete"));
                    end
                    idx = [];
                    Twiddle_Array_Over_Epoch = [];
                    epoch = [];
                    Play_Progress_ButtonPushed(app)
                else
                    app.Play_Progress_Button.Text = 'Play Progress over Epoch';
                    app.Play_Progress_Button.BackgroundColor = [0.96, 0.96, 0.96];
                    
                    clear Twiddle_Array_Over_Epochs; clear idx; clear epoch;
                    cla(app.Twiddle_Mag_Plot)
                    cla(app.Twiddle_Phase_Plot)
                end
            catch ME
                waitfor(errordlg(ME.message, "Error During Twiddle Data Plotting"));
            end
        end

        % Value changed function: Standard_Select
        function Standard_Select_ValueChanged(app, event)
            value = app.Standard_Select.Value;
            
            directory = pwd;
            image_path = strcat(directory, '\Demonstration Images\');
            switch value
                case "DMR"
                    % Adjust Diagram to suit
                    app.Tx.ImageSource = strcat(image_path, 'DMR_tx.PNG');
                    app.Rx.ImageSource = strcat(image_path, 'DMR_rx.PNG');
                    app.Available_Symbols.Value = "[0, 1]";
                    
                case "IQ FM"
                    % Adjust Diagram to suit
                    app.Tx.ImageSource = strcat(image_path, 'IQ_tx.PNG');
                    app.Rx.ImageSource = strcat(image_path, 'IQ_rx.PNG');
                    app.Available_Symbols.Value = "[0, 1]";
                    
                case "ZIF FM"
                    % Adjust Diagram to suit
                    app.Tx.ImageSource = strcat(image_path, 'ZIF_tx.PNG');
                    app.Rx.ImageSource = strcat(image_path, 'ZIF_rx.PNG');
                    app.Available_Symbols.Value = "[0, 1, 2]";
                    
            end
            
        end

        % Value changed function: Medium_Select
        function Medium_Select_ValueChanged(app, event)
            value = app.Medium_Select.Value;
            
            directory = pwd;
            image_path = strcat(directory, '\Demonstration Images\');
            switch value
                case "RF Cable"
                    % Adjust Diagram to suit
                    app.Medium.ImageSource = strcat(image_path, 'Cable.PNG');
                    
                case "Free Space"
                    % Adjust Diagram to suit
                    app.Medium.ImageSource = strcat(image_path, 'Free Space.png');
                    
            end
        end

        % Button pushed function: Run_Model
        function Run_Model_ButtonPushed(app, event)
            try
                if ~strcmp(app.Run_Model.Text, "Stop Model")
                    app.Run_Model.BackgroundColor = [0.45, 0.95, 0.45];
                    app.Run_Model.Text = "Stop Model";
                    drawnow()
                    
                    while ~strcmp(app.Run_Model.Text, "Run Model")
                        app.Norm_Received_Data.Value = '[]';
                        app.ML_Received_Data.Value = '[]';
                        
                        % Update all input values for the Demonstration
                        update_demo_input_vals(app)
                        
                        % Extract the available symbols from the front panel
                        symbols_str = app.Available_Symbols.Value;
                        split1 = split(symbols_str, '[');
                        split2 = split(split1{2}, ']');
                        symbols_split_str = split(split2{1}, ',');
                        symbols = [];
                        for i = 1:size(symbols_split_str)
                            symbols = [symbols, str2double(symbols_split_str{i})];
                        end
                        clear symbols_str; clear split1; clear split2; clear symbols_split_str;
                        
                        % With the available symbols determined, next we
                        % generate a random array composed of these
                        % symbols. This will be the Tx Data.
                        Transmit_Data = [];
                        Norm_Receive_Data = [];
                        ML_Receive_Data = [];
                        
                        Tx_str = '[';
                        for i = 1:8
                            index = randi(size(symbols), 1);
                            Transmit_Data = [Transmit_Data, symbols(index)];
                            if i ~= 8
                                Tx_str = strcat(Tx_str, num2str(symbols(index)), ', ');
                            else
                                Tx_str = strcat(Tx_str, num2str(symbols(index)));
                            end
                        end
                        Tx_str = strcat(Tx_str, ']');
                        app.Transmitted_Data.Value = Tx_str;
                        drawnow()
                        
                        % Now simulate the system according to user
                        % specifications
                        switch app.Standard_Select.Value
                            case 'DMR'
                                for i = 1:size(Transmit_Data, 2)
                                    %-------------------------------
                                    % Tx
                                    %-------------------------------
                                    % Start by generating the Tx
                                    % signal in this system
                                    amplitude = -(app.amplitude_noise/2) + (app.amplitude_noise) * rand(1, app.signal_length);
                                    phase = -(app.phase_noise/2) + (app.phase_noise) * rand(1, app.signal_length);
                                    if Transmit_Data(i) == 0
                                        if app.LO_Leakage.Value
                                            app.signal_offset = -app.Demo_Signal_Offset.Value + ((-app.Demo_Signal_Offset.Value/4) + ((app.Demo_Signal_Offset.Value/2) * rand(1, 1)));
                                        else
                                            app.signal_offset = -app.Demo_Signal_Offset.Value;
                                        end
                                    else
                                        if app.LO_Leakage.Value
                                            app.signal_offset = app.Demo_Signal_Offset.Value + ((-app.Demo_Signal_Offset.Value/4) + ((app.Demo_Signal_Offset.Value/2) * rand(1, 1)));
                                        else
                                            app.signal_offset = app.Demo_Signal_Offset.Value;
                                        end
                                    end
                                    
                                    app.carrier_freq = app.Demo_Carrier_Freq.Value + app.signal_offset;
                                    
                                    Tx_signal = generate_signal(app, amplitude, phase);
                                    
                                    if app.PA_Non_Linearity.Value
                                        
                                    end
                                    
                                    % Now let's plot this signal on screen
                                    [Tx_fft_freq, Tx_freq, Tx_fft_mag] = fft_and_post_process(app, Tx_signal);
                                    plot(app.Tx_Freq, Tx_fft_freq, Tx_fft_mag)
                                    drawnow()
                                    %-------------------------------
                                    
                                    % Medium
                                    %-------------------------------
                                    % Add a certain amount of noise based
                                    % on the medium type and quality
                                    switch app.Medium_Select.Value
                                        case 'RF Cable'
                                            % Attenuate the signal slightly
                                            Cable_Loss = 0.9;
                                            Rx_signal = Tx_signal * Cable_Loss;
                                            switch app.Medium_Clarity.Value
                                                case 'Perfect'
                                                case 'Good'
                                                case 'Average'
                                                case 'Poor'
                                                case 'Bad'
                                            end
                                            
                                        case 'Free Space'
                                            % Apply the Free Space Loss
                                            % coefficient. 
                                            lambda = physconst('LightSpeed')/app.carrier_freq;
                                            R = 10e3;
                                            Free_Space_Loss = (4 * pi * R) / lambda;
                                            
                                            % Scale the signal by this amount, bringing the
                                            % signal tone closer to the
                                            % noise floor
                                            Rx_frequency = (Tx_freq / Free_Space_Loss)  + ((max(Tx_freq / Free_Space_Loss) / 2) * rand(1, app.signal_length));
                                            switch app.Medium_Clarity.Value
                                                case 'Perfect'
                                                    % Allow the signal
                                                    % through without
                                                    % distortion
                                                    Rx_signal = ifft(Rx_frequency);
                                                case 'Good'
                                                    % Add a blocker signal
                                                    % and allow the data
                                                    % through with minimal
                                                    % distortion
                                                    amplitude = -(app.amplitude_noise/2) + (app.amplitude_noise) * rand(1, app.signal_length);
                                                    phase = -(app.phase_noise/2) + (app.phase_noise) * rand(1, app.signal_length);
                                                    
                                                    app.carrier_freq = (app.sampling_freq/2) * rand(1, 1);
                                                    app.carrier_amp = 4;
                                                    blocker_signal = generate_signal(app, amplitude, phase);
                                                    
                                                    Rx_signal = ifft(Rx_frequency) + blocker_signal;
                                                case 'Average'
                                                    % Gently distort the
                                                    % signal and add a
                                                    % blocker.
                                                    Rx_frequency = smoothdata(Rx_frequency, 'gaussian', 2);
                                                    
                                                    amplitude = -(app.amplitude_noise/2) + (app.amplitude_noise) * rand(1, app.signal_length);
                                                    phase = -(app.phase_noise/2) + (app.phase_noise) * rand(1, app.signal_length);
                                                    
                                                    app.carrier_freq = (app.sampling_freq/2) * rand(1, 1);
                                                    app.carrier_amp = 4;
                                                    blocker_signal = generate_signal(app, amplitude, phase);
                                                    
                                                    Rx_signal = ifft(Rx_frequency) + blocker_signal;
                                                case 'Poor'
                                                    % Moderately distort
                                                    % the signal and add
                                                    % a blocker
                                                    Rx_frequency = smoothdata(Rx_frequency, 'gaussian', 3);
                                                    
                                                    amplitude = -(app.amplitude_noise/2) + (app.amplitude_noise) * rand(1, app.signal_length);
                                                    phase = -(app.phase_noise/2) + (app.phase_noise) * rand(1, app.signal_length);
                                                    
                                                    app.carrier_freq = (app.sampling_freq/2) * rand(1, 1);
                                                    app.carrier_amp = 4;
                                                    blocker_signal = generate_signal(app, amplitude, phase);
                                                    
                                                    Rx_signal = ifft(Rx_frequency) + blocker_signal;
                                                case 'Bad'
                                                    % heavily distort
                                                    % the signal and add
                                                    % a blocker
                                                    Rx_frequency = smoothdata(Rx_frequency, 'gaussian', 4);
                                                    
                                                    amplitude = -(app.amplitude_noise/2) + (app.amplitude_noise) * rand(1, app.signal_length);
                                                    phase = -(app.phase_noise/2) + (app.phase_noise) * rand(1, app.signal_length);
                                                    
                                                    app.carrier_freq = (app.sampling_freq/2) * rand(1, 1);
                                                    app.carrier_amp = 4;
                                                    blocker_signal = generate_signal(app, amplitude, phase);
                                                    
                                                    Rx_signal = ifft(Rx_frequency) + blocker_signal;
                                            end
                                            
                                            % Reset the carrier aplitude
                                            % before next iteration.
                                            app.carrier_amp = 20;
                                    end
                                    %-------------------------------
                                    
                                    % Rx
                                    %-------------------------------
                                    % First let's plot the received signal
                                    [Rx_fft_freq, ~, Rx_fft_mag] = fft_and_post_process(app, Rx_signal);
                                    plot(app.Rx_Freq, Rx_fft_freq, Rx_fft_mag)
                                    drawnow()
                                    
                                    lower_0_limit = app.Demo_Carrier_Freq.Value - (1.025 * app.Demo_Signal_Offset.Value);
                                    upper_0_limit = app.Demo_Carrier_Freq.Value - (0.975 * app.Demo_Signal_Offset.Value);
                                    lower_1_limit = app.Demo_Carrier_Freq.Value + (0.975 * app.Demo_Signal_Offset.Value);
                                    upper_1_limit = app.Demo_Carrier_Freq.Value + (1.025 * app.Demo_Signal_Offset.Value);
                                    
                                    Rx_filtered = bandpass(Rx_signal, [(lower_0_limit),  (upper_1_limit)], app.sampling_freq);
                                                                        
                                    % With the Rx_signal now filtered
                                    % sufficiently, the next job is input
                                    % discrimination. 
                                    % 
                                    % Given the radio
                                    % standard being used here, all
                                    % discriminatin can be done in SW. 
                                    [Rx_fft_freq, ~, Rx_filtered_fft_mag] = fft_and_post_process(app, Rx_filtered);
                                    
                                    % Define the upper and lower limits on
                                    % each frequency's meaning.
                                    [~, low_0] = min(abs(Rx_fft_freq - lower_0_limit));
                                    [~, high_0] = min(abs(Rx_fft_freq - upper_0_limit));
                                    [~, low_1] = min(abs(Rx_fft_freq - lower_1_limit));
                                    [~, high_1] = min(abs(Rx_fft_freq - upper_1_limit));
                                    
                                    % Search the 0 and 1 regions for strong
                                    % tones which would indicate data.
                                    zero_detected = (max(Rx_filtered_fft_mag(low_0 : high_0)) > 2.5 * mean(Rx_filtered_fft_mag(low_0 : high_0)));
                                    one_detected = (max(Rx_filtered_fft_mag(low_1 : high_1)) > 2.5 * mean(Rx_filtered_fft_mag(low_1 : high_1)));
                                    
                                    % Fill Rx data buffer
                                    if zero_detected && ~one_detected
                                        Norm_Receive_Data = [Norm_Receive_Data, 0];
                                    elseif one_detected && ~zero_detected
                                        Norm_Receive_Data = [Norm_Receive_Data, 1];
                                    else
                                        Norm_Receive_Data = [Norm_Receive_Data, -1];
                                    end
                                    
                                    Norm_string = app.Norm_Received_Data.Value;
                                    Norm_string_split = split(Norm_string, ']');
                                    if i == 1
                                        app.Norm_Received_Data.Value = strcat(Norm_string_split{1}, num2str(Norm_Receive_Data(1, end)), ']');
                                    else
                                        app.Norm_Received_Data.Value = strcat(Norm_string_split{1}, ',', num2str(Norm_Receive_Data(1, end)), ']');
                                    end
                                    
                                    pause(1);
                                end
                                
                                % Compute and display error info for
                                % comparrison.
                                
                            case 'IQ FM'
                                for i = 1:size(Transmit_Data, 2)
                                    %-------------------------------
                                    % Tx
                                    %-------------------------------
                                    % Start by generating the ideal
                                    % Tx signal in this system
                                    
                                    %-------------------------------
                                    
                                    % Medium
                                    %-------------------------------
                                    % Add a certain amount of noise based
                                    % on the medium type and quality
                                    switch app.Medium_Select.Value
                                        case 'RF Cable'
                                            switch app.Medium_Clarity.Value
                                                case 'Perfect'
                                                case 'Good'
                                                case 'Average'
                                                case 'Poor'
                                                case 'Bad'
                                            end
                                        case 'Free Space'
                                            switch app.Medium_Clarity.Value
                                                case 'Perfect'
                                                case 'Good'
                                                case 'Average'
                                                case 'Poor'
                                                case 'Bad'
                                            end
                                    end
                                    %-------------------------------
                                    
                                    % Rx
                                    %-------------------------------
                                end
                                
                            case 'ZIF FM'
                                for i = 1:size(Transmit_Data, 2)
                                    %-------------------------------
                                    % Tx
                                    %-------------------------------
                                    % Start by generating the ideal
                                    % Tx signal in this system
                                    
                                    %-------------------------------
                                    
                                    % Medium
                                    %-------------------------------
                                    % Add a certain amount of noise based
                                    % on the medium type and quality
                                    switch app.Medium_Select.Value
                                        case 'RF Cable'
                                            switch app.Medium_Clarity.Value
                                                case 'Perfect'
                                                case 'Good'
                                                case 'Average'
                                                case 'Poor'
                                                case 'Bad'
                                            end
                                        case 'Free Space'
                                            switch app.Medium_Clarity.Value
                                                case 'Perfect'
                                                case 'Good'
                                                case 'Average'
                                                case 'Poor'
                                                case 'Bad'
                                            end
                                    end
                                    %-------------------------------
                                    
                                    % Rx
                                    %-------------------------------
                                end
                        end
                    end
                    
                else
                    % Reset the Demo and clear all unnecessary data
                    app.Run_Model.BackgroundColor = [0.96, 0.96, 0.96];
                    app.Run_Model.Text = "Run Model";
                end
            catch ME
                waitfor(errordlg(ME.message, "Error During Simulation Run"));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Intelligent_Signal_Reader = uifigure('Visible', 'off');
            app.Intelligent_Signal_Reader.Name = 'Intelligent DFT';
            app.Intelligent_Signal_Reader.CloseRequestFcn = createCallbackFcn(app, @Intelligent_Signal_ReaderCloseRequest, true);
            app.TabGroup = uitabgroup(app.Intelligent_Signal_Reader);
            app.Concept = uitab(app.TabGroup);
            app.Concept.Title = 'Proof of Concept';
            app.SignalLength2Label = uilabel(app.Concept);
            app.SignalLength2Label.Text = 'Signal Length (2^...)';
            app.Signal_Length_In = uieditfield(app.Concept, 'numeric');
            app.Signal_Length_In.Limits = [0 Inf];
            app.Signal_Length_In.RoundFractionalValues = 'on';
            app.Signal_Length_In.Value = 12;
            app.CarrierFreqHzEditFieldLabel = uilabel(app.Concept);
            app.CarrierFreqHzEditFieldLabel.Text = 'Carrier Freq (Hz)';
            app.Carrier_Freq_In = uieditfield(app.Concept, 'numeric');
            app.Carrier_Freq_In.Limits = [0 Inf];
            app.Carrier_Freq_In.Value = 10000;
            app.CarrierAmplitudeVLabel = uilabel(app.Concept);
            app.CarrierAmplitudeVLabel.Text = 'Carrier Amplitude (V)';
            app.Carrier_Amp_In = uieditfield(app.Concept, 'numeric');
            app.Carrier_Amp_In.Value = 5;
            app.AmplitudeNoiseLabel = uilabel(app.Concept);
            app.AmplitudeNoiseLabel.Text = 'Amplitude Noise';
            app.Amplitude_Noise_Knob = uiknob(app.Concept, 'continuous');
            app.PhaseNoiseLabel = uilabel(app.Concept);
            app.PhaseNoiseLabel.Text = 'Phase Noise';
            app.Phase_Noise_Knob = uiknob(app.Concept, 'continuous');
            app.Phase_Noise_Knob.Limits = [0 20];
            app.FMModulationLabel = uilabel(app.Concept);
            app.FMModulationLabel.Text = 'FM Modulation';
            app.Add_Signal = uiswitch(app.Concept, 'toggle');
            app.FrequencyOffsetLabel = uilabel(app.Concept);
            app.FrequencyOffsetLabel.Text = 'Frequency Offset';
            app.Ctrl_Sig_Freq = uiswitch(app.Concept, 'toggle');
            app.Ctrl_Sig_Freq.Items = {'Constant', 'Random'};
            app.Ctrl_Sig_Freq.Value = 'Constant';
            app.Normal_Axes = uiaxes(app.Concept);
            title(app.Normal_Axes, 'Normal FFT')
            xlabel(app.Normal_Axes, 'Frequency (Hz)')
            ylabel(app.Normal_Axes, 'Magnitude')
            app.PSNRLabel = uilabel(app.Concept);
            app.PSNRLabel.Text = 'PSNR';
            app.Normal_PSNR = uieditfield(app.Concept, 'numeric');
            app.Normal_PSNR.Editable = 'off';
            app.SignalOffsetHzLabel = uilabel(app.Concept);
            app.SignalOffsetHzLabel.Text = 'Signal Offset (Hz)';
            app.Signal_Offset = uieditfield(app.Concept, 'numeric');
            app.SamplingFreqHzEditFieldLabel = uilabel(app.Concept);
            app.SamplingFreqHzEditFieldLabel.Text = 'Sampling Freq (Hz)';
            app.Sampling_Freq_In = uieditfield(app.Concept, 'numeric');
            app.Sampling_Freq_In.Limits = [0 Inf];
            app.Sampling_Freq_In.Value = 30000;
            app.Training_Ctrls = uipanel(app.Concept);
            app.Training_Ctrls.Title = 'Training Controls';
            app.LearningRateLabel = uilabel(app.Training_Ctrls);
            app.LearningRateLabel.Text = 'Learning Rate';
            app.LR_Update = uidropdown(app.Training_Ctrls);
            app.LR_Update.Items = {'Constant', 'Root of Sum of Squares', '6th root of Sum of Mags', '|mean(loss)| / |max(loss)|', 'mean(|loss|) / max(|loss|)', 'EVM'};
            app.LR_Update.Value = 'Constant';
            app.LearnRLabel = uilabel(app.Training_Ctrls);
            app.LearnRLabel.Text = 'Learn R';
            app.LR_Numeric = uieditfield(app.Training_Ctrls, 'numeric');
            app.LR_Numeric.Limits = [0 Inf];
            app.LR_Numeric.Value = 1e-06;
            app.RegularisationLabel = uilabel(app.Training_Ctrls);
            app.RegularisationLabel.Text = 'Regularisation';
            app.RR_Update = uidropdown(app.Training_Ctrls);
            app.RR_Update.Items = {'Constant', 'Root of Sum of Squares', '6th root of Sum of Mags', '|mean(complexity)| / |max(complexity)|', 'mean(|complexity|) / max(|complexity|)', 'EVM'};
            app.RR_Update.Value = 'Constant';
            app.RegularRLabel = uilabel(app.Training_Ctrls);
            app.RegularRLabel.Text = 'Regular R';
            app.RR_Numeric = uieditfield(app.Training_Ctrls, 'numeric');
            app.RR_Numeric.Limits = [0 Inf];
            app.RR_Numeric.Value = 1e-06;
            app.Run_Training_Button = uibutton(app.Training_Ctrls, 'push');
            app.Run_Training_Button.ButtonPushedFcn = createCallbackFcn(app, @Run_Training_ButtonPushed, true);
            app.Run_Training_Button.Text = 'Run Training';
            app.Reset_Button = uibutton(app.Training_Ctrls, 'push');
            app.Reset_Button.ButtonPushedFcn = createCallbackFcn(app, @Reset_ButtonPushed, true);
            app.Reset_Button.Text = 'Reset';
            app.MaxNumEpochsLabel = uilabel(app.Training_Ctrls);
            app.MaxNumEpochsLabel.Text = 'Max Num. Epochs';
            app.Num_Epochs_In = uieditfield(app.Training_Ctrls, 'numeric');
            app.Num_Epochs_In.Limits = [0 Inf];
            app.Num_Epochs_In.RoundFractionalValues = 'on';
            app.Num_Epochs_In.Value = 5;
            app.WeightUpdateLabel = uilabel(app.Training_Ctrls);
            app.WeightUpdateLabel.Text = 'Weight Update';
            app.Weight_Update = uidropdown(app.Training_Ctrls);
            app.Weight_Update.Items = {'dE/dW', 'sqrt(dE/dW)', '(dE/dW)/max(dE/dW)'};
            app.Weight_Update.Value = 'dE/dW';
            app.RegFunctionLabel = uilabel(app.Training_Ctrls);
            app.RegFunctionLabel.Text = 'Reg. Function';
            app.Regularisation = uidropdown(app.Training_Ctrls);
            app.Regularisation.Items = {'Linear', 'sqrt(Complexity)', '(Complexity)/max(Complexity)'};
            app.Regularisation.Value = 'Linear';
            app.LossFunctionLabel = uilabel(app.Training_Ctrls);
            app.LossFunctionLabel.Text = 'Loss Function';
            app.Loss_Func_In = uidropdown(app.Training_Ctrls);
            app.Loss_Func_In.Items = {'Difference', 'Difference Squared', 'Difference of Squares', 'EVM'};
            app.Loss_Func_In.Value = 'Difference';
            app.ComplexityLabel = uilabel(app.Training_Ctrls);
            app.ComplexityLabel.Text = 'Complexity';
            app.Complexity_In = uidropdown(app.Training_Ctrls);
            app.Complexity_In.Items = {'None', 'W_train - W_orig', '(W_train - W_orig)^2', '(W_train^2 - W_orig^2)', 'sqrt(W_train^2 - W_orig^2)'};
            app.Complexity_In.Value = 'None';
            app.Export_Training_Data_Button = uibutton(app.Training_Ctrls, 'push');
            app.Export_Training_Data_Button.ButtonPushedFcn = createCallbackFcn(app, @Export_Training_Data_ButtonPushed, true);
            app.Export_Training_Data_Button.Text = 'Export Training Data';
            app.Store_Training_Data = uicheckbox(app.Training_Ctrls);
            app.Store_Training_Data.Text = 'Store Training Data';
            app.Ideal_Axes = uiaxes(app.Concept);
            title(app.Ideal_Axes, 'Ideal Signal')
            xlabel(app.Ideal_Axes, 'Frequency (Hz)')
            ylabel(app.Ideal_Axes, 'Magnitude')
            app.Predicted_Axes = uiaxes(app.Concept);
            title(app.Predicted_Axes, 'Predicted DFT Using ML')
            xlabel(app.Predicted_Axes, 'Frequency (Hz)')
            ylabel(app.Predicted_Axes, 'Magnitude')
            app.PSNRLabel_2 = uilabel(app.Concept);
            app.PSNRLabel_2.Text = 'PSNR';
            app.Predicted_PSNR = uieditfield(app.Concept, 'numeric');
            app.Predicted_PSNR.Editable = 'off';
            app.MSELabel = uilabel(app.Concept);
            app.MSELabel.Text = 'MSE';
            app.Normal_MSE = uieditfield(app.Concept, 'numeric');
            app.Normal_MSE.Editable = 'off';
            app.MSELabel_2 = uilabel(app.Concept);
            app.MSELabel_2.Text = 'MSE';
            app.Predicted_MSE = uieditfield(app.Concept, 'numeric');
            app.Predicted_MSE.Editable = 'off';
            app.MaxErrLabel = uilabel(app.Concept);
            app.MaxErrLabel.Text = 'Max Err';
            app.Normal_MaxErr = uieditfield(app.Concept, 'numeric');
            app.Normal_MaxErr.Editable = 'off';
            app.MaxErrLabel_2 = uilabel(app.Concept);
            app.MaxErrLabel_2.Text = 'Max Err';
            app.Predicted_MaxErr = uieditfield(app.Concept, 'numeric');
            app.Predicted_MaxErr.Editable = 'off';
            app.L2ratLabel = uilabel(app.Concept);
            app.L2ratLabel.Text = 'L2rat';
            app.Normal_L2rat = uieditfield(app.Concept, 'numeric');
            app.Normal_L2rat.Editable = 'off';
            app.L2ratLabel_2 = uilabel(app.Concept);
            app.L2ratLabel_2.Text = 'L2rat';
            app.Predicted_L2rat = uieditfield(app.Concept, 'numeric');
            app.Predicted_L2rat.Editable = 'off';
            app.Report = uitab(app.TabGroup);
            app.Report.Title = 'Training Report';
            app.Generate_Report_Button = uibutton(app.Report, 'push');
            app.Generate_Report_Button.ButtonPushedFcn = createCallbackFcn(app, @Generate_Report_ButtonPushed, true);
            app.Generate_Report_Button.Text = 'Generate Report';
            app.PSNR_Plot = uiaxes(app.Report);
            title(app.PSNR_Plot, 'PSNR Over Epoch')
            xlabel(app.PSNR_Plot, 'Epoch')
            ylabel(app.PSNR_Plot, 'PSNR')
            app.MSE_Plot = uiaxes(app.Report);
            title(app.MSE_Plot, 'MSE Over Epoch')
            xlabel(app.MSE_Plot, 'Epoch')
            ylabel(app.MSE_Plot, 'MSE')
            app.MaxErr_Plot = uiaxes(app.Report);
            title(app.MaxErr_Plot, 'MaxErr over Epoch')
            xlabel(app.MaxErr_Plot, 'Epoch')
            ylabel(app.MaxErr_Plot, 'MaxErr')
            app.L2rat_Plot = uiaxes(app.Report);
            title(app.L2rat_Plot, 'L2 Ratio over Epoch')
            xlabel(app.L2rat_Plot, 'Epoch')
            ylabel(app.L2rat_Plot, 'L2 Ratio')
            app.Play_Progress_Button = uibutton(app.Report, 'push');
            app.Play_Progress_Button.ButtonPushedFcn = createCallbackFcn(app, @Play_Progress_ButtonPushed, true);
            app.Play_Progress_Button.Text = 'Play Progress over Epoch';
            app.Twiddle_Mag_Plot = uiaxes(app.Report);
            title(app.Twiddle_Mag_Plot, 'Twiddle Array Magnitude over Epoch')
            xlabel(app.Twiddle_Mag_Plot, 'Row')
            ylabel(app.Twiddle_Mag_Plot, 'Col')
            app.Twiddle_Phase_Plot = uiaxes(app.Report);
            title(app.Twiddle_Phase_Plot, 'Twiddle Array Phase over Epoch')
            xlabel(app.Twiddle_Phase_Plot, 'Row')
            ylabel(app.Twiddle_Phase_Plot, 'Col')
            app.Demonstration = uitab(app.TabGroup);
            app.Demonstration.Title = 'Demonstration';
            app.System_Model = uipanel(app.Demonstration);
            app.System_Model.Title = 'System Model';
            app.Tx = uiimage(app.System_Model);
            app.Medium = uiimage(app.System_Model);
            app.Rx = uiimage(app.System_Model);
            app.StandardSelectionLabel = uilabel(app.System_Model);
            app.StandardSelectionLabel.Text = 'Standard Selection';
            app.Standard_Select = uidropdown(app.System_Model);
            app.Standard_Select.Items = {'DMR', 'IQ FM', 'ZIF FM'};
            app.Standard_Select.ValueChangedFcn = createCallbackFcn(app, @Standard_Select_ValueChanged, true);
            app.Standard_Select.Value = 'DMR';
            app.MediumSelectionLabel = uilabel(app.System_Model);
            app.MediumSelectionLabel.Text = 'Medium Selection';
            app.Medium_Select = uidropdown(app.System_Model);
            app.Medium_Select.Items = {'RF Cable', 'Free Space'};
            app.Medium_Select.ValueChangedFcn = createCallbackFcn(app, @Medium_Select_ValueChanged, true);
            app.Medium_Select.Value = 'RF Cable';
            app.CarrierFreqHzEditFieldLabel_2 = uilabel(app.System_Model);
            app.CarrierFreqHzEditFieldLabel_2.Text = 'Carrier Freq (Hz)';
            app.Demo_Carrier_Freq = uieditfield(app.System_Model, 'numeric');
            app.Demo_Carrier_Freq.Limits = [0 Inf];
            app.Demo_Carrier_Freq.Value = 10000;
            app.SignalOffsetHzLabel_2 = uilabel(app.System_Model);
            app.SignalOffsetHzLabel_2.Text = 'Signal Offset (Hz)';
            app.Demo_Signal_Offset = uieditfield(app.System_Model, 'numeric');
            app.SamplingFreqHzEditFieldLabel_2 = uilabel(app.System_Model);
            app.SamplingFreqHzEditFieldLabel_2.Text = 'Sampling Freq (Hz)';
            app.Demo_Sampling_Freq = uieditfield(app.System_Model, 'numeric');
            app.Demo_Sampling_Freq.Limits = [0 Inf];
            app.Demo_Sampling_Freq.Value = 30000;
            app.TxLabel = uilabel(app.System_Model);
            app.TxLabel.Text = 'Tx';
            app.MediumLabel = uilabel(app.System_Model);
            app.MediumLabel.Text = 'Medium';
            app.RxLabel = uilabel(app.System_Model);
            app.RxLabel.Text = 'Rx';
            app.NumSamples2Label = uilabel(app.System_Model);
            app.NumSamples2Label.Text = 'Num Samples (2^...)';
            app.Demo_Num_Samples = uieditfield(app.System_Model, 'numeric');
            app.Demo_Num_Samples.Limits = [0 Inf];
            app.Demo_Num_Samples.RoundFractionalValues = 'on';
            app.Demo_Num_Samples.Value = 12;
            app.SystemSetupLabel = uilabel(app.System_Model);
            app.SystemSetupLabel.Text = 'System Setup';
            app.SystemImperfectionsLabel = uilabel(app.System_Model);
            app.SystemImperfectionsLabel.Text = 'System Imperfections';
            app.Quadrature_Error = uicheckbox(app.System_Model);
            app.Quadrature_Error.Text = 'Quadrature Error';
            app.LO_Leakage = uicheckbox(app.System_Model);
            app.LO_Leakage.Text = 'LO Leakage';
            app.PA_Non_Linearity = uicheckbox(app.System_Model);
            app.PA_Non_Linearity.Text = 'PA Non-Linearity';
            app.MediumClarityLabel = uilabel(app.System_Model);
            app.MediumClarityLabel.Text = 'Medium Clarity';
            app.Medium_Clarity = uidropdown(app.System_Model);
            app.Medium_Clarity.Items = {'Perfect', 'Good', 'Average', 'Poor', 'Bad'};
            app.Medium_Clarity.Value = 'Perfect';
            app.Run_Initial_Calibration = uicheckbox(app.System_Model);
            app.Run_Initial_Calibration.Text = 'Run Initial Calibration';
            app.Run_Model = uibutton(app.System_Model, 'push');
            app.Run_Model.ButtonPushedFcn = createCallbackFcn(app, @Run_Model_ButtonPushed, true);
            app.Run_Model.Text = 'Run Model';
            app.TrainCfgDropDownLabel = uilabel(app.System_Model);
            app.TrainCfgDropDownLabel.Text = 'Train Cfg';
            app.TrainCfgDropDown = uidropdown(app.System_Model);
            app.TrainCfgDropDown.Items = {'Linear Training', 'Linear Training w/ Reg.'};
            app.TrainCfgDropDown.Value = 'Linear Training';
            app.Tx_Data = uipanel(app.Demonstration);
            app.Tx_Data.Title = 'Transmit Data';
            app.AvailableSymbolsEditFieldLabel = uilabel(app.Tx_Data);
            app.AvailableSymbolsEditFieldLabel.Text = 'Available Symbols';
            app.Available_Symbols = uieditfield(app.Tx_Data, 'text');
            app.Available_Symbols.Editable = 'off';
            app.Available_Symbols.Value = '[0, 1]';
            app.TransmittedDataEditFieldLabel = uilabel(app.Tx_Data);
            app.TransmittedDataEditFieldLabel.Text = 'Transmitted Data';
            app.Transmitted_Data = uieditfield(app.Tx_Data, 'text');
            app.Transmitted_Data.Editable = 'off';
            app.Transmitted_Data.Value = '[ ]';
            app.Tx_Freq = uiaxes(app.Tx_Data);
            title(app.Tx_Freq, 'Tx Frequency')
            xlabel(app.Tx_Freq, 'Frequency')
            ylabel(app.Tx_Freq, 'Level')
            app.Rx_Data = uipanel(app.Demonstration);
            app.Rx_Data.Title = 'Recieve Data';
            app.MLReceivedDataLabel = uilabel(app.Rx_Data);
            app.MLReceivedDataLabel.Text = 'ML Received Data';
            app.ML_Received_Data = uieditfield(app.Rx_Data, 'text');
            app.ML_Received_Data.Editable = 'off';
            app.ML_Received_Data.Value = '[ ]';
            app.NormalReceivedDataLabel = uilabel(app.Rx_Data);
            app.NormalReceivedDataLabel.Text = 'Normal Received Data';
            app.Norm_Received_Data = uieditfield(app.Rx_Data, 'text');
            app.Norm_Received_Data.Editable = 'off';
            app.Norm_Received_Data.Value = '[ ]';
            app.Rx_Freq = uiaxes(app.Rx_Data);
            title(app.Rx_Freq, 'Rx Frequency')
            xlabel(app.Rx_Freq, 'Frequency (Hz)')
            ylabel(app.Rx_Freq, 'Level')
            app.Error = uipanel(app.Demonstration);
            app.Error.Title = 'Error';
            app.MLSymbolErrorRateLabel = uilabel(app.Error);
            app.MLSymbolErrorRateLabel.Text = 'ML Symbol Error Rate (%)';
            app.ML_Symbol_Error = uieditfield(app.Error, 'numeric');
            app.ML_Symbol_Error.Editable = 'off';
            app.NormSymbolErrorRateLabel = uilabel(app.Error);
            app.NormSymbolErrorRateLabel.Text = 'Norm Symbol Error Rate (%)';
            app.Norm_Symbol_Error = uieditfield(app.Error, 'numeric');
            % Show the figure after all components are created
            app.Intelligent_Signal_Reader.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Inteligent_DFT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Intelligent_Signal_Reader)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Intelligent_Signal_Reader)
        end
    end
end